classdef GUI_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InsertTimeButton                matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        Timesofstimulation              matlab.ui.control.EditField
        TimesofstimulationhhmmssEditFieldLabel  matlab.ui.control.Label
        EnterFilterOrderEditField       matlab.ui.control.NumericEditField
        EnterFilterOrderEditFieldLabel  matlab.ui.control.Label
        SelectcutoffFrequencySlider     matlab.ui.control.Slider
        SelectcutoffFrequencySliderLabel  matlab.ui.control.Label
        FiletoreadDropDown              matlab.ui.control.DropDown
        FiletoreadDropDownLabel         matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        Tab3                            matlab.ui.container.Tab
        Tab2                            matlab.ui.container.Tab
        FilteringTab                    matlab.ui.container.Tab
        EnterFilterOrderEditField_2     matlab.ui.control.NumericEditField
        EnterFilterOrderEditField_2Label  matlab.ui.control.Label
        SelectcutoffFrequencyforToniccomponentSlider  matlab.ui.control.Slider
        SelectcutoffFrequencyforToniccomponentLabel  matlab.ui.control.Label
        PreProcessingFilterLabel        matlab.ui.control.Label
        PhasicandTonicseperationmethodButtonGroup  matlab.ui.container.ButtonGroup
        Button3                         matlab.ui.control.RadioButton
        Button2                         matlab.ui.control.RadioButton
        FilteringButton                 matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fs=3;
        DirPath = ['C:\Users\alonz\OneDrive - Technion\תואר\סמסטר 6\פרויקט\' ...
                'project - Stress Detection with a Smart Ring\Ring Samples\CSV'];   
        Time
        secsFromStart
        raw
        acc
        st
        ii
        MM
        SCR
        stimulationTimes = NaT(1);


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,"center");
            filesList = dir(app.DirPath);
            filesNames = strings;
            
            for jj = 1:(length(filesList)-2)
                filesNames(end+1,:) = filesList(jj+2).name;
            end
            filesNames = sort(filesNames(2:end));
            app.FiletoreadDropDown.Items = filesNames;


        end

        % Value changed function: FiletoreadDropDown
        function FiletoreadDropDownValueChanged(app, event)
            fileName = app.FiletoreadDropDown.Value;
            filePath = append(app.DirPath,append('\',fileName));
            T = readtable(filePath);
            app.Time = timeofday(T.date_time);
            app.secsFromStart = T.time_sec_;
            app.raw = T.raw;
            app.acc = [T.ax, T.ay, T.az];
            app.st = T.st;
            app.ii = T.ii;
            app.MM = T.mm;
            app.SCR = T.scr;
            app.stimulationTimes = NaT(1);
            

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            %preprocess:
            order = app.EnterFilterOrderEditField.Value;
            cutoffFreq = app.SelectcutoffFrequencySlider.Value;
            preProccesedData = preprocess(app.raw,app.fs,order,cutoffFreq);

            %extract tonic and phasic components:
            [tonic, phasic] = tonicPhasicFilter(preProccesedData,app.fs);

            %plot:
            plot(app.UIAxes,app.Time,preProccesedData,app.Time,phasic,app.Time,tonic);
            grid(app.UIAxes,'on');
            if ~isempty(app.stimulationTimes)
                tStim = timeofday(datetime(app.stimulationTimes,'InputFormat','HH:mm:ss'));
                xline(app.UIAxes,tStim);
            end
            legend(app.UIAxes,'Preproccesed Data', 'phasic','tonic');

        end

        % Button pushed function: InsertTimeButton
        function InsertTimeButtonPushed(app, event)
            stimulationTime = datetime(app.Timesofstimulation.Value);
            app.stimulationTimes(end+1) = stimulationTime;
            app.Timesofstimulation.Value = '';


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PhasicandTonicseperationmethodButtonGroup = uibuttongroup(app.UIFigure);
            app.PhasicandTonicseperationmethodButtonGroup.Title = 'Phasic and Tonic seperation method';
            app.FilteringButton = uiradiobutton(app.PhasicandTonicseperationmethodButtonGroup);
            app.FilteringButton.Text = 'Filtering';
            app.FilteringButton.Value = true;
            app.Button2 = uiradiobutton(app.PhasicandTonicseperationmethodButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.PhasicandTonicseperationmethodButtonGroup);
            app.Button3.Text = 'Button3';
            app.PreProcessingFilterLabel = uilabel(app.UIFigure);
            app.PreProcessingFilterLabel.Text = 'PreProcessing Filter';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FilteringTab = uitab(app.TabGroup);
            app.FilteringTab.Title = 'Filtering';
            app.SelectcutoffFrequencyforToniccomponentLabel = uilabel(app.FilteringTab);
            app.SelectcutoffFrequencyforToniccomponentLabel.Text = {'Select cutoff Frequency '; 'for Tonic component'};
            app.SelectcutoffFrequencyforToniccomponentSlider = uislider(app.FilteringTab);
            app.SelectcutoffFrequencyforToniccomponentSlider.Limits = [0 0.1];
            app.SelectcutoffFrequencyforToniccomponentSlider.MajorTicks = [0 0.05 0.1];
            app.SelectcutoffFrequencyforToniccomponentSlider.MinorTicks = [0 0.005 0.01 0.015 0.02 0.025 0.03 0.035 0.04 0.045 0.05 0.055 0.06 0.065 0.07 0.075 0.08 0.085 0.09 0.095 0.1];
            app.SelectcutoffFrequencyforToniccomponentSlider.Value = 0.05;
            app.EnterFilterOrderEditField_2Label = uilabel(app.FilteringTab);
            app.EnterFilterOrderEditField_2Label.Text = 'Enter Filter Order';
            app.EnterFilterOrderEditField_2 = uieditfield(app.FilteringTab, 'numeric');
            app.EnterFilterOrderEditField_2.Value = 32;
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.FiletoreadDropDownLabel = uilabel(app.UIFigure);
            app.FiletoreadDropDownLabel.Text = 'File to read';
            app.FiletoreadDropDown = uidropdown(app.UIFigure);
            app.FiletoreadDropDown.ValueChangedFcn = createCallbackFcn(app, @FiletoreadDropDownValueChanged, true);
            app.SelectcutoffFrequencySliderLabel = uilabel(app.UIFigure);
            app.SelectcutoffFrequencySliderLabel.Text = 'Select cutoff Frequency';
            app.SelectcutoffFrequencySlider = uislider(app.UIFigure);
            app.SelectcutoffFrequencySlider.Limits = [0.1 1];
            app.SelectcutoffFrequencySlider.MajorTicks = [0.1 0.5 1];
            app.SelectcutoffFrequencySlider.MinorTicks = [0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.SelectcutoffFrequencySlider.Value = 0.1;
            app.EnterFilterOrderEditFieldLabel = uilabel(app.UIFigure);
            app.EnterFilterOrderEditFieldLabel.Text = 'Enter Filter Order';
            app.EnterFilterOrderEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterFilterOrderEditField.Value = 32;
            app.TimesofstimulationhhmmssEditFieldLabel = uilabel(app.UIFigure);
            app.TimesofstimulationhhmmssEditFieldLabel.Text = 'Times of stimulation (hh:mm:ss)';
            app.Timesofstimulation = uieditfield(app.UIFigure, 'text');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.InsertTimeButton = uibutton(app.UIFigure, 'push');
            app.InsertTimeButton.ButtonPushedFcn = createCallbackFcn(app, @InsertTimeButtonPushed, true);
            app.InsertTimeButton.Text = 'Insert Time';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end